classdef combining_em_waves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        AppTitle                    matlab.ui.control.Label
        DisplayPanel                matlab.ui.container.Panel
        DurationsecEditField        matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel   matlab.ui.control.Label
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        Wave1Panel                  matlab.ui.container.Panel
        metresLabel                 matlab.ui.control.Label
        AmpLabel                    matlab.ui.control.Label
        AmplitudeSlider             matlab.ui.control.Slider
        WavelengthSlider            matlab.ui.control.Slider
        WavelengthSliderLabel       matlab.ui.control.Label
        AmplitudeSliderLabel        matlab.ui.control.Label
        Wave2Panel                  matlab.ui.container.Panel
        degreesLabel                matlab.ui.control.Label
        metresLabel_2               matlab.ui.control.Label
        AmpLabel_2                  matlab.ui.control.Label
        PhaseSliderLabel            matlab.ui.control.Label
        AmplitudeSlider_2           matlab.ui.control.Slider
        WavelengthSlider_2          matlab.ui.control.Slider
        PhaseSlider                 matlab.ui.control.Slider
        WavelengthSlider_2Label     matlab.ui.control.Label
        AmplitudeSlider_2Label      matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes_Wave1                matlab.ui.control.UIAxes
        UIAxes_Wave2                matlab.ui.control.UIAxes
        UIAxes_Combined             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
            
        function updateplot(app) 
            % Get values from UI components
            amplitude1 = app.AmplitudeSlider.Value;
            wavelength1 = app.WavelengthSlider.Value;
            % ... (and so on for other sliders, sampling rate, duration)
    
            % Calculate the wave equations (implement these equations)
            wave1 = calculateWave(amplitude1, wavelength1, phase1, ...);
            wave2 = calculateWave(amplitude2, wavelength2, phase2, ...);
            combinedWave = wave1 + wave2; 
    
            % Plot the waves
            plot(app.UIAxes_2, time, wave1);
            plot(app.UIAxes_3, time, wave2);
            plot(app.UIAxes_4, time, combinedWave);
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateplot(app)
        end

        % Value changed function: AmplitudeSlider
        function Amplitude1ValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {666, 666};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Wave2Panel = uipanel(app.LeftPanel);
            app.Wave2Panel.Title = 'Wave 2';
            app.AmplitudeSlider_2Label = uilabel(app.Wave2Panel);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.WavelengthSlider_2Label = uilabel(app.Wave2Panel);
            app.WavelengthSlider_2Label.Text = 'Wavelength';
            app.PhaseSlider = uislider(app.Wave2Panel);
            app.PhaseSlider.Limits = [-180 180];
            app.WavelengthSlider_2 = uislider(app.Wave2Panel);
            app.WavelengthSlider_2.Limits = [0 10];
            app.WavelengthSlider_2.Value = 4;
            app.AmplitudeSlider_2 = uislider(app.Wave2Panel);
            app.AmplitudeSlider_2.Limits = [0 10];
            app.AmplitudeSlider_2.Value = 5;
            app.PhaseSliderLabel = uilabel(app.Wave2Panel);
            app.PhaseSliderLabel.Text = 'Phase';
            app.AmpLabel_2 = uilabel(app.Wave2Panel);
            app.AmpLabel_2.Text = 'Amp ';
            app.metresLabel_2 = uilabel(app.Wave2Panel);
            app.metresLabel_2.Text = 'metres ';
            app.degreesLabel = uilabel(app.Wave2Panel);
            app.degreesLabel.Text = 'degrees';
            app.Wave1Panel = uipanel(app.LeftPanel);
            app.Wave1Panel.Title = 'Wave 1';
            app.AmplitudeSliderLabel = uilabel(app.Wave1Panel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.WavelengthSliderLabel = uilabel(app.Wave1Panel);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.Wave1Panel);
            app.WavelengthSlider.Limits = [0 10];
            app.WavelengthSlider.Value = 4;
            app.AmplitudeSlider = uislider(app.Wave1Panel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @Amplitude1ValueChanged, true);
            app.AmplitudeSlider.Value = 5;
            app.AmpLabel = uilabel(app.Wave1Panel);
            app.AmpLabel.Text = 'Amp ';
            app.metresLabel = uilabel(app.Wave1Panel);
            app.metresLabel.Text = 'metres ';
            app.DisplayPanel = uipanel(app.LeftPanel);
            app.DisplayPanel.Title = 'Display';
            app.SamplingRateEditFieldLabel = uilabel(app.DisplayPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.SamplingRateEditField.Limits = [100 8000];
            app.SamplingRateEditField.RoundFractionalValues = 'on';
            app.SamplingRateEditField.Value = 100;
            app.DurationsecEditFieldLabel = uilabel(app.DisplayPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 10];
            app.DurationsecEditField.Value = 5;
            app.AppTitle = uilabel(app.LeftPanel);
            app.AppTitle.Text = 'Combining EM Waves';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Combined = uiaxes(app.RightPanel);
            title(app.UIAxes_Combined, 'Wave 1 + Wave 2')
            xlabel(app.UIAxes_Combined, 'Time (sec)')
            ylabel(app.UIAxes_Combined, 'Amplitude (V)')
            app.UIAxes_Wave2 = uiaxes(app.RightPanel);
            title(app.UIAxes_Wave2, 'Wave 2')
            xlabel(app.UIAxes_Wave2, 'Time (sec)')
            ylabel(app.UIAxes_Wave2, 'Amplitude (V)')
            app.UIAxes_Wave1 = uiaxes(app.RightPanel);
            title(app.UIAxes_Wave1, 'Wave 1')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combining_em_waves

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end