classdef freq_modulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        AppTitle                    matlab.ui.control.Label
        MessageSignalPanel          matlab.ui.container.Panel
        AmplitudeSlider             matlab.ui.control.Slider
        AmpLabel                    matlab.ui.control.Label
        FrequencySlider             matlab.ui.control.Slider
        FreqLabel                   matlab.ui.control.Label
        FrequencySliderLabel        matlab.ui.control.Label
        AmplitudeSliderLabel        matlab.ui.control.Label
        CarrierSignalPanel          matlab.ui.container.Panel
        FrequencySlider_2           matlab.ui.control.Slider
        FreqLabel_2                 matlab.ui.control.Label
        FrequencySlider_2Label      matlab.ui.control.Label
        ModulatedSignalPanel        matlab.ui.container.Panel
        ModulationIndexSlider       matlab.ui.control.Slider
        IndLabel                    matlab.ui.control.Label
        FrequencyRatioSlider        matlab.ui.control.Slider
        FrequencyRatioSliderLabel   matlab.ui.control.Label
        FreqRatioLabel              matlab.ui.control.Label
        ModulationIndexSliderLabel  matlab.ui.control.Label
        DisplayPanel                matlab.ui.container.Panel
        WaveOverlayCheckBox         matlab.ui.control.CheckBox
        WaveOverlayLabel            matlab.ui.control.Label
        DurationsecEditField        matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel   matlab.ui.control.Label
        ResetWavesButton            matlab.ui.control.Button
        ResetDisplayButton          matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes_Message              matlab.ui.control.UIAxes
        UIAxes_Carrier              matlab.ui.control.UIAxes
        UIAxes_Modulated            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function updateplot(app)
            % Display
            samplePerCycle = 100;
            waveOverlay = app.WaveOverlayCheckBox.Value;
            duration = app.DurationsecEditField.Value;
        end

        function reset(app)
            app.AmplitudeSlider.Value = 1;
            app.FrequencySlider.Value = 2;
            app.FrequencySlider_2.Value = 25;
            app.ModulationIndexSlider.Value = 1;
            app.FrequencyRatioSlider.Value = 10;

            resetLabels(app);
            updateplot(app);
        end

        function resetLabels(app)
            app.AmpLabel.Text = num2str(round(app.AmplitudeSlider.Value));
            app.FreqLabel.Text = num2str(round(app.FrequencySlider.Value));
            app.FreqLabel_2.Text = num2str(round(app.FrequencySlider_2.Value));
            app.IndLabel.Text = num2str(round(app.ModulationIndexSlider.Value, 2));
            app.FreqRatioLabel.Text = num2str(round(app.FrequencyRatioSlider.Value, 2));
        end

        function resetDisplay(app)
            app.WaveOverlayCheckBox.Value = 0;
            app.DurationsecEditField.Value = 2;
          
            updateplot(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetLabels(app);
            updateplot(app);
        end

        % Button pushed function: ResetWavesButton
        function ResetWavesButtonPushed(app, event)
            reset(app);
        end

        % Button pushed function: ResetDisplayButton
        function ResetDisplayButtonPushed(app, event)
            resetDisplay(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {674, 674};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {200, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetDisplayButton = uibutton(app.LeftPanel, 'push');
            app.ResetDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDisplayButtonPushed, true);
            app.ResetDisplayButton.Text = 'Display ↺';
            app.ResetWavesButton = uibutton(app.LeftPanel, 'push');
            app.ResetWavesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButtonPushed, true);
            app.ResetWavesButton.Text = 'Waves ↺';
            app.DisplayPanel = uipanel(app.LeftPanel);
            app.DisplayPanel.Title = 'Display';
            app.DurationsecEditFieldLabel = uilabel(app.DisplayPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 60];
            app.DurationsecEditField.Value = 2;
            app.WaveOverlayLabel = uilabel(app.DisplayPanel);
            app.WaveOverlayLabel.Text = 'Wave Overlay';
            app.WaveOverlayCheckBox = uicheckbox(app.DisplayPanel);
            app.WaveOverlayCheckBox.Text = '';
            app.ModulatedSignalPanel = uipanel(app.LeftPanel);
            app.ModulatedSignalPanel.Title = 'Modulated Signal';
            app.ModulationIndexSliderLabel = uilabel(app.ModulatedSignalPanel);
            app.ModulationIndexSliderLabel.Text = 'Modulation Index';
            app.FreqRatioLabel = uilabel(app.ModulatedSignalPanel);
            app.FreqRatioLabel.Text = 'Freq';
            app.FrequencyRatioSliderLabel = uilabel(app.ModulatedSignalPanel);
            app.FrequencyRatioSliderLabel.Text = 'Frequency Ratio';
            app.FrequencyRatioSlider = uislider(app.ModulatedSignalPanel);
            app.FrequencyRatioSlider.Limits = [10 100];
            app.FrequencyRatioSlider.Value = 10;
            app.IndLabel = uilabel(app.ModulatedSignalPanel);
            app.IndLabel.Text = 'Ind';
            app.ModulationIndexSlider = uislider(app.ModulatedSignalPanel);
            app.ModulationIndexSlider.Limits = [0 5];
            app.ModulationIndexSlider.MajorTicks = [0 1 2 3 4 5];
            app.ModulationIndexSlider.Value = 1;
            app.CarrierSignalPanel = uipanel(app.LeftPanel);
            app.CarrierSignalPanel.Title = 'Carrier Signal';
            app.FrequencySlider_2Label = uilabel(app.CarrierSignalPanel);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FreqLabel_2 = uilabel(app.CarrierSignalPanel);
            app.FreqLabel_2.Text = 'Freq';
            app.FrequencySlider_2 = uislider(app.CarrierSignalPanel);
            app.FrequencySlider_2.Limits = [10 100];
            app.FrequencySlider_2.MajorTicks = [10 25 50 75 100];
            app.FrequencySlider_2.Value = 10;
            app.MessageSignalPanel = uipanel(app.LeftPanel);
            app.MessageSignalPanel.Title = 'Message Signal';
            app.AmplitudeSliderLabel = uilabel(app.MessageSignalPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.FrequencySliderLabel = uilabel(app.MessageSignalPanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FreqLabel = uilabel(app.MessageSignalPanel);
            app.FreqLabel.Text = 'Freq';
            app.FrequencySlider = uislider(app.MessageSignalPanel);
            app.FrequencySlider.Limits = [1 9];
            app.FrequencySlider.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider.MinorTicks = [2 4 6 8];
            app.FrequencySlider.Value = 4;
            app.AmpLabel = uilabel(app.MessageSignalPanel);
            app.AmpLabel.Text = 'Amp ';
            app.AmplitudeSlider = uislider(app.MessageSignalPanel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider.Value = 5;
            app.AppTitle = uilabel(app.LeftPanel);
            app.AppTitle.Text = 'Freq. Modulation';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Modulated = uiaxes(app.RightPanel);
            title(app.UIAxes_Modulated, 'Modulated Signal')
            xlabel(app.UIAxes_Modulated, 'Time (sec)')
            ylabel(app.UIAxes_Modulated, 'Amplitude (V)')
            zlabel(app.UIAxes_Modulated, 'Amplitude (V)')
            app.UIAxes_Carrier = uiaxes(app.RightPanel);
            title(app.UIAxes_Carrier, 'Carrier Signal')
            xlabel(app.UIAxes_Carrier, 'Time (sec)')
            ylabel(app.UIAxes_Carrier, 'Amplitude (V)')
            zlabel(app.UIAxes_Carrier, 'Amplitude (V)')
            app.UIAxes_Message = uiaxes(app.RightPanel);
            title(app.UIAxes_Message, 'Message Signal')
            xlabel(app.UIAxes_Message, 'Time (sec)')
            ylabel(app.UIAxes_Message, 'Amplitude (V)')
            zlabel(app.UIAxes_Message, 'Amplitude (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = freq_modulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end