classdef medium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AppTitle                     matlab.ui.control.Label
        ResetMediumButton            matlab.ui.control.Button
        ResetDisplayButton           matlab.ui.control.Button
        ResetWavesButton_2           matlab.ui.control.Button
        WavePanel                    matlab.ui.container.Panel
        FreqLabel                    matlab.ui.control.Label
        AmpLabel                     matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        WaveMedium                   matlab.ui.container.Panel
        LenLabel                     matlab.ui.control.Label
        RefraLabel                   matlab.ui.control.Label
        ExtinLabel                   matlab.ui.control.Label
        LengthSliderLabel            matlab.ui.control.Label
        LengthSlider                 matlab.ui.control.Slider
        RefractionIndexSliderLabel   matlab.ui.control.Label
        ExtinctioncoefficientSlider  matlab.ui.control.Slider
        RefractionIndexSlider        matlab.ui.control.Slider
        ExtinctioncoefficientSliderLabel  matlab.ui.control.Label
        DisplayPanel                 matlab.ui.container.Panel
        PerspectiveDropDownLabel     matlab.ui.control.Label
        PerspectiveDropDown          matlab.ui.control.DropDown
        DurationsecEditField         matlab.ui.control.NumericEditField
        DurationsecLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_Wave1                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function updateplot(app) % Good version
            
            % Display 
            perspective = app.PerspectiveDropDown.Value;
            samplePerCycle = 100;
            duration = app.DurationsecEditField.Value;

            % labels
            app.ExtinLabel.Text = num2str(round(app.ExtinctioncoefficientSlider.Value, 2));
            app.RefraLabel.Text = num2str(round(app.RefractionIndexSlider.Value, 2));
            app.LenLabel.Text = num2str(round(app.LengthSlider.Value, 2));

            % Wave1
            fc1 = round(app.FrequencySlider.Value, 2);
            amp1 = round(app.AmplitudeSlider.Value, 2);
            fs1 = fc1 * samplePerCycle;

            t1 = 0:(1/fs1):(duration-1/fs1);
            S1 = amp1 * sin(2*pi*fc1*t1);

            ylim(app.UIAxes_Wave1, [-10, 10]);
            plot(app.UIAxes_Wave1, t1, S1, 'Color', 'red'); 

            % Modulation Logic
            refractionIndex = app.RefractionIndexSlider.Value;
            extinctionCoefficient = app.ExtinctioncoefficientSlider.Value;
            modulationExtent = app.LengthSlider.Value / 100;
            
            center = duration / 2;
            half_extent = modulationExtent * duration / 0.8;
            start_modulation = center - half_extent;
            end_modulation = center + half_extent;

            %shaded region
            mod_region_x = [start_modulation, end_modulation, end_modulation, start_modulation];
            mod_region_y = [-10, -10, 10, 10];
            phase_start = 2*pi*fc1*start_modulation;
            phase_end = 2*pi*fc1*end_modulation;

            
            % Wave inside the shaded area
            S3 = S1;
            
            modulated_fc1 = fc1 * refractionIndex;
            modulation_mask = (t1 >= start_modulation) & (t1 <= end_modulation);
            phase_shift = linspace(phase_start, phase_end, sum(modulation_mask));
            S3(modulation_mask) = amp1 * sin(2*pi*modulated_fc1*(t1(modulation_mask) - start_modulation) + phase_shift);
            
            % Apply extinction coefficient
            S3_cropped = S3(modulation_mask);
            t1_cropped = t1(modulation_mask);

            S3_cropped = S3_cropped .* exp(-extinctionCoefficient * (t1_cropped - start_modulation)) .* exp(-extinctionCoefficient * t1_cropped);
            S3(modulation_mask) = S3_cropped;
            

            % num 2
            last_peak_index = find(modulation_mask, 1, 'last');
            last_peak_amplitude = S3(last_peak_index);
            time_after_modulation = t1(last_peak_index+1:end) - t1(last_peak_index); 
            
            last_phase_shift = 2*pi*modulated_fc1*(t1(last_peak_index) - start_modulation) + phase_shift(end); 
            next_modulation_index = find(modulation_mask, 1, 'last') + 1; 
            
            sine_wave_after_shaded_area = last_peak_amplitude * sin(2*pi*modulated_fc1*time_after_modulation + last_phase_shift);
            
            S3(next_modulation_index:end) = sine_wave_after_shaded_area;


            % Plot the modulated wave
            if (perspective == "3D")
                plot3(app.UIAxes, t1, S3, zeros(size(t1)), 'Color', 'blue');
            else % 2D
                plot(app.UIAxes, t1, S3, 'Color', 'blue');
                view(app.UIAxes, 2);
            end

            % Plot the shaded region
            hold(app.UIAxes, 'on');
            fill(app.UIAxes, mod_region_x, mod_region_y, 'r', 'FaceAlpha', 0.3 , 'LineWidth',5);
            hold(app.UIAxes, 'off');

            ylim(app.UIAxes, [-10, 10]);
        end

        function resetWaveLabels(app)
            app.AmpLabel.Text = num2str(round(app.AmplitudeSlider.Value));
            app.FreqLabel.Text = num2str(round(app.FrequencySlider.Value));
        end

        function resetWaves(app)
            app.AmplitudeSlider.Value = 5;
            app.FrequencySlider.Value = 4;
            
            resetWaveLabels(app);
            updateplot(app);
        end

        function resetMediumLabels(app)
            app.ExtinLabel.Text = num2str(round(app.ExtinctioncoefficientSlider.Value, 2));
            app.RefraLabel.Text = num2str(round(app.RefractionIndexSlider.Value, 2));
            app.LenLabel.Text = num2str(round(app.LengthSlider.Value, 3));
        end

        function resetMedium(app)
            app.LengthSlider.Value = 8;
            app.ExtinctioncoefficientSlider.Value = 0.5;
            app.RefractionIndexSlider.Value = 1.5;
            
            resetMediumLabels(app);
            updateplot(app);
        end

        function resetDisplay(app)
            app.PerspectiveDropDown.ValueIndex = 1;
            app.DurationsecEditField.Value = 2;
          
            updateplot(app);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateplot(app)
        end

        % Callback function
        function SamplingRateEditFieldValueChanged(app, event)
            updateplot(app);  
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            app.FreqLabel.Text=num2str(round(app.FrequencySlider.Value));
            updateplot(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            app.AmpLabel.Text=num2str(round(app.AmplitudeSlider.Value));
            updateplot(app);
        end

        % Callback function
        function ResetWavesButtonPushed(app, event)
            resetWaves(app);
        end

        % Value changed function: RefractionIndexSlider
        function RefractionIndexSliderValueChanged(app, event)
            app.RefraLabel.Text=num2str(round(app.RefractionIndexSlider.Value, 2));
            updateplot(app);
        end

        % Value changed function: ExtinctioncoefficientSlider
        function ExtinctioncoefficientSliderValueChanged(app, event)
            app.ExtinLabel.Text=num2str(round(app.ExtinctioncoefficientSlider.Value, 2));
            updateplot(app);
        end

        % Value changed function: LengthSlider
        function LengthSliderValueChanged(app, event)
            app.LenLabel.Text=num2str(round(app.LengthSlider.Value), 2);
            updateplot(app);
        end

        % Button pushed function: ResetWavesButton_2
        function ResetWavesButton_2Pushed(app, event)
            resetWaves(app);
        end

        % Value changed function: PerspectiveDropDown
        function PerspectiveDropDownValueChanged(app, event)
            updateplot(app);
        end

        % Button pushed function: ResetMediumButton
        function ResetMediumButtonPushed(app, event)
            resetMedium(app);
        end

        % Button pushed function: ResetDisplayButton
        function ResetDisplayButtonPushed(app, event)
            resetDisplay(app);
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AmpLabel.Text=num2str(round(changingValue));
            app.AmplitudeSlider.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.FreqLabel.Text=num2str(round(changingValue));
            app.FrequencySlider.Value=round(changingValue);
            updateplot(app);
        end

        % Value changing function: ExtinctioncoefficientSlider
        function ExtinctioncoefficientSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ExtinLabel.Text=num2str(round(changingValue, 2));
            app.ExtinctioncoefficientSlider.Value=round(changingValue, 2);
            updateplot(app);
        end

        % Value changing function: RefractionIndexSlider
        function RefractionIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RefraLabel.Text=num2str(round(changingValue, 2));
            app.RefractionIndexSlider.Value=round(changingValue, 2);
            updateplot(app);
        end

        % Value changing function: LengthSlider
        function LengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LenLabel.Text=num2str(round(changingValue, 2));
            app.LengthSlider.Value=round(changingValue, 2);
            updateplot(app);
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Wave1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Wave1, 'Original Wave')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Through a Medium')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.DurationsecLabel = uilabel(app.DisplayPanel);
            app.DurationsecLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 60];
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.DurationsecEditField.Value = 2;
            app.PerspectiveDropDown = uidropdown(app.DisplayPanel);
            app.PerspectiveDropDown.Items = {'2D', '3D'};
            app.PerspectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @PerspectiveDropDownValueChanged, true);
            app.PerspectiveDropDown.Value = '2D';
            app.PerspectiveDropDownLabel = uilabel(app.DisplayPanel);
            app.PerspectiveDropDownLabel.Text = 'Perspective';
            app.WaveMedium = uipanel(app.UIFigure);
            app.WaveMedium.Title = 'Medium';
            app.ExtinctioncoefficientSliderLabel = uilabel(app.WaveMedium);
            app.ExtinctioncoefficientSliderLabel.Text = 'Extinction coefficient';
            app.RefractionIndexSlider = uislider(app.WaveMedium);
            app.RefractionIndexSlider.Limits = [1 2];
            app.RefractionIndexSlider.ValueChangedFcn = createCallbackFcn(app, @RefractionIndexSliderValueChanged, true);
            app.RefractionIndexSlider.ValueChangingFcn = createCallbackFcn(app, @RefractionIndexSliderValueChanging, true);
            app.RefractionIndexSlider.Value = 1.5;
            app.ExtinctioncoefficientSlider = uislider(app.WaveMedium);
            app.ExtinctioncoefficientSlider.Limits = [0 1];
            app.ExtinctioncoefficientSlider.ValueChangedFcn = createCallbackFcn(app, @ExtinctioncoefficientSliderValueChanged, true);
            app.ExtinctioncoefficientSlider.ValueChangingFcn = createCallbackFcn(app, @ExtinctioncoefficientSliderValueChanging, true);
            app.ExtinctioncoefficientSlider.Value = 0.5;
            app.RefractionIndexSliderLabel = uilabel(app.WaveMedium);
            app.RefractionIndexSliderLabel.Text = 'Refraction Index';
            app.LengthSlider = uislider(app.WaveMedium);
            app.LengthSlider.Limits = [1 32];
            app.LengthSlider.MajorTicks = [1 4 8 12 16 20 24 28 32];
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @LengthSliderValueChanged, true);
            app.LengthSlider.ValueChangingFcn = createCallbackFcn(app, @LengthSliderValueChanging, true);
            app.LengthSlider.MinorTicks = [2 3 5 6 7 9 10 11 13 14 15 17 18 19 21 22 23 25 26 27 29 30 31];
            app.LengthSlider.Value = 8;
            app.LengthSliderLabel = uilabel(app.WaveMedium);
            app.LengthSliderLabel.Text = 'Length';
            app.ExtinLabel = uilabel(app.WaveMedium);
            app.ExtinLabel.Text = 'Ext';
            app.RefraLabel = uilabel(app.WaveMedium);
            app.RefraLabel.Text = 'Refra';
            app.LenLabel = uilabel(app.WaveMedium);
            app.LenLabel.Text = 'Len';
            app.WavePanel = uipanel(app.UIFigure);
            app.WavePanel.Title = 'Wave';
            app.AmplitudeSliderLabel = uilabel(app.WavePanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.WavePanel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider.Value = 5;
            app.FrequencySliderLabel = uilabel(app.WavePanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.WavePanel);
            app.FrequencySlider.Limits = [1 9];
            app.FrequencySlider.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.MinorTicks = [2 4 6 8];
            app.FrequencySlider.Value = 4;
            app.AmpLabel = uilabel(app.WavePanel);
            app.AmpLabel.Text = 'Amp ';
            app.FreqLabel = uilabel(app.WavePanel);
            app.FreqLabel.Text = 'Freq';
            app.ResetWavesButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetWavesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButton_2Pushed, true);
            app.ResetWavesButton_2.Text = 'Waves ↺';
            app.ResetDisplayButton = uibutton(app.UIFigure, 'push');
            app.ResetDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDisplayButtonPushed, true);
            app.ResetDisplayButton.Text = 'Display ↺';
            app.ResetMediumButton = uibutton(app.UIFigure, 'push');
            app.ResetMediumButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMediumButtonPushed, true);
            app.ResetMediumButton.Text = 'Medium ↺';
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'Waves Through a Medium';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end