classdef voiceModulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetWavesButton           matlab.ui.control.Button
        ModulationTechniquesPanel  matlab.ui.container.Panel
        AddNoiseButton             matlab.ui.control.StateButton
        RecordPanel                matlab.ui.container.Panel
        PlayAudioButton            matlab.ui.control.Button
        DeleteCurrentAudioButton   matlab.ui.control.Button
        RecordAudioButton          matlab.ui.control.Button
        UIAxes_Wave1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RecordedAudioDataProperty % RecordedAudioData from the user
        ModulatedAudioData % Adjusted Audio from the user
        Fs = 44100;   % Sampling frequency 
    end
    
    methods (Access = private)
        

        function updateplot(app)

            if ~isempty(app.RecordedAudioDataProperty)                
                t = (0:length(app.RecordedAudioDataProperty)-1) / app.Fs;

                plot(app.UIAxes_Wave1, t, app.ModulatedAudioData, 'Color', 'blue');
                title(app.UIAxes_Wave1, 'Recorded Wave');
                xlabel(app.UIAxes_Wave1, 'Time (s)');
                ylabel(app.UIAxes_Wave1, 'Amplitude');
            else
                % If no recorded audio data, clear the plot
                cla(app.UIAxes_Wave1);
                title(app.UIAxes_Wave1, '');
            end

        end

        
        function recordAudio(app)
            % Specify audio parameters
            nBits = 16; % Number of bits
            nChannels = 1; % Number of channels (mono)
            recordingDuration = 5; % Specify recording duration (in seconds)
    
            % Create an audiorecorder object
            recObj = audiorecorder(app.Fs, nBits, nChannels);
    
            disp(['Start speaking. Recording for ', int2str(recordingDuration), ' sec'])
            app.RecordAudioButton.Text = "Start speaking";

            recordblocking(recObj, recordingDuration);
            app.RecordAudioButton.Text = "Record New Audio";
            disp('End of Recording');
    
            % Get the recorded audio data
            audioSampleData = getaudiodata(recObj);
            app.RecordedAudioDataProperty = audioSampleData;
            app.ModulatedAudioData = app.RecordedAudioDataProperty;
            
            % Disable relevant buttons
            app.DeleteCurrentAudioButton.Enable = 'on'; 
            app.PlayAudioButton.Enable ="on";
            app.AddNoiseButton.Enable = "on";
            
            updateplot(app);
        end
      
        function playAudio(app)
            if ~isempty(app.RecordedAudioDataProperty)

                audioPlayer = audioplayer(app.ModulatedAudioData, 44100); 
                
                % Play the audio
                disp('Playing audio...');
                app.PlayAudioButton.Text = "Playing audio...";
                playblocking(audioPlayer); 
                app.PlayAudioButton.Text = "Play Again";
                disp('Audio playback finished.');

            else
                errordlg('No recorded audio to play.', 'Error', 'modal');
                app.PlayAudioButton.Text = "No Audio To Play";
            end
        end

        function deleteAudio(app)
            % Delete the recorded audio data
            app.RecordedAudioDataProperty = [];
            
            % Update the plot to reflect the deletion
            updateplot(app);
            
            % Disable relevant buttons
            app.RecordAudioButton.Text = "Record Audio";
            app.PlayAudioButton.Text = "Play Audio";
            app.DeleteCurrentAudioButton.Enable = 'off'; 
            app.PlayAudioButton.Enable ="off";
        end


        function addNoise(app)
                % Check if there is recorded audio data
            if isempty(app.RecordedAudioDataProperty)
                % If no recorded data, display an error message
                errordlg('No recorded audio to add noise to.', 'Error', 'modal');
                return;
            end
        
            % Generate Gaussian noise with the same length as the recorded audio
            
            rng('default'); % Reset random number generator
            noise = 0.01 * randn(size(app.RecordedAudioDataProperty)); 
        
            % Add noise to the recorded audio
            app.ModulatedAudioData = app.ModulatedAudioData + noise;
            app.AddNoiseButton.Text = "Add More Noise"
            % Update the plot with the modulated audio waveform
            updateplot(app);

        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable relevant buttons
            app.DeleteCurrentAudioButton.Enable = 'off'; 
            app.PlayAudioButton.Enable ="off";
            app.AddNoiseButton.Enable = "off";

            updateplot(app);
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recordAudio(app);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            playAudio(app);
        end

        % Button pushed function: DeleteCurrentAudioButton
        function DeleteCurrentAudioButtonPushed(app, event)
            deleteAudio(app);
        end

        % Button pushed function: ResetWavesButton
        function ResetWavesButtonPushed(app, event)
            deleteAudio(app);
        end

        % Value changed function: AddNoiseButton
        function AddNoiseButtonValueChanged(app, event)
            addNoise(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Wave1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Wave1, 'Recorded Wave')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.RecordAudioButton = uibutton(app.RecordPanel, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.DeleteCurrentAudioButton = uibutton(app.RecordPanel, 'push');
            app.DeleteCurrentAudioButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentAudioButtonPushed, true);
            app.DeleteCurrentAudioButton.Text = 'Delete Current Audio';
            app.PlayAudioButton = uibutton(app.RecordPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ModulationTechniquesPanel = uipanel(app.UIFigure);
            app.ModulationTechniquesPanel.Title = 'Modulation Techniques';
            app.AddNoiseButton = uibutton(app.ModulationTechniquesPanel, 'state');
            app.AddNoiseButton.ValueChangedFcn = createCallbackFcn(app, @AddNoiseButtonValueChanged, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.ResetWavesButton = uibutton(app.UIFigure, 'push');
            app.ResetWavesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButtonPushed, true);
            app.ResetWavesButton.Text = 'Waves ↺';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceModulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end